classdef DVZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        RightPanel       matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        StartStopButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Self-defined properties
    properties (Access = private)
        Robot = struct( ...
            'length', 0.78, ...
            'width', 0.51, ...
            'x', 0, ...
            'y', 0, ...
            'theta', 0, ...
            'lin_vel', 0, ...
            'ang_vel', 0 ...
            );
        deltaT = 0.01;
        Timer = timer("ExecutionMode", "fixedRate", "BusyMode", "queue");
        
        % theta = 0;
        % t = -10:0.01:10;
    end
    
    % Self-defined methods
    methods (Access = private)
        % Update robot position based on velocities
        function updateRobotPose(app)
            app.Robot.x = app.Robot.x + app.deltaT * app.Robot.lin_vel * cos(app.Robot.theta);
            app.Robot.y = app.Robot.y + app.deltaT * app.Robot.lin_vel * sin(app.Robot.theta);
            app.Robot.theta = app.Robot.theta + app.deltaT * app.Robot.ang_vel;
        end

        function updateRobotPlot(app)
            plot(app.UIAxes, app.Robot.x, app.Robot.y, 'o', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
        end

        % Update everything at period `deltaT`
        function timerCallback(app)
            updateRobotPose(app);
            
            % hold(app.UIAxes, 'on');
            updateRobotPlot(app);
            % hold(app.UIAxes, 'off');

            % plot(app.UIAxes, app.t, sin(app.t+app.theta));
            % app.theta = app.theta + pi/180;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Timer.Period = app.deltaT;
            app.Timer.TimerFcn = @(~,~) app.timerCallback;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Update velocities based on key press
            switch event.Key
                case 'w'
                    app.Robot.lin_vel = app.Robot.lin_vel + 0.05;
                case 's'
                    app.Robot.lin_vel = app.Robot.lin_vel - 0.05;
                case 'a'
                    app.Robot.ang_vel = app.Robot.ang_vel + 0.01;
                case 'd'
                    app.Robot.ang_vel = app.Robot.ang_vel - 0.01;
            end
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if app.StartStopButton.Text == "Start"
                if strcmp(app.Timer.Running,"off")
                    start(app.Timer);
                    app.StartStopButton.Text = "Stop";
                end
            elseif app.StartStopButton.Text == "Stop"
                stop(app.Timer);
                app.StartStopButton.Text = "Start";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Timer);
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.StartStopButton = uibutton(app.GridLayout2, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DVZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end